classdef systemModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        DummyMenu                  matlab.ui.container.Menu
        ShortcutsMenu              matlab.ui.container.Menu
        DroptobaseworkspaceMenu    matlab.ui.container.Menu
        UpdatesystemMenu           matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        SystemTab                  matlab.ui.container.Tab
        TButton                    matlab.ui.control.Button
        impulseResponse            matlab.ui.control.UIAxes
        amplitudeResponse          matlab.ui.control.UIAxes
        phaseResponse              matlab.ui.control.UIAxes
        aButton                    matlab.ui.control.Button
        CouplingButton             matlab.ui.control.Button
        TXantennaButton            matlab.ui.control.Button
        DButton                    matlab.ui.control.Button
        DelaydButton               matlab.ui.control.Button
        DelayRButton               matlab.ui.control.Button
        DelayR0Button_2            matlab.ui.control.Button
        TagButton                  matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        BackgroundButton           matlab.ui.control.Button
        OButton                    matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        SourceButton               matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        RXantennaButton            matlab.ui.control.Button
        DetectorButton             matlab.ui.control.Button
        bButton                    matlab.ui.control.Button
        D_dButton                  matlab.ui.control.Button
        T_RButton                  matlab.ui.control.Button
        T_R0Button                 matlab.ui.control.Button
        DelayRButton_2             matlab.ui.control.Button
        DelayR0Button              matlab.ui.control.Button
        C_RButton                  matlab.ui.control.Button
        O_R0Button                 matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        dEditFieldLabel            matlab.ui.control.Label
        dEditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        R0EditFieldLabel           matlab.ui.control.Label
        R0EditField                matlab.ui.control.NumericEditField
        NoiseButton                matlab.ui.control.Button
        NoiseButton_2              matlab.ui.control.Button
        UpdateButton               matlab.ui.control.Button
        systemSideView             matlab.ui.control.UIAxes
        SelectTaglocationButton    matlab.ui.control.Button
        SetupTab                   matlab.ui.container.Tab
        droptobaseworkspaceButton  matlab.ui.control.Button
        blockSetupTab              matlab.ui.container.Tab
        systemBlockList            matlab.ui.control.ListBox
        gainEditFieldLabel         matlab.ui.control.Label
        gainEditField              matlab.ui.control.NumericEditField
        SaveButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        shift=0
    end
    
    properties (Access = public)
        systemStruct
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.systemStruct=systeminit(app);
        end

        % Button pushed function: CButton, C_RButton, DButton, 
        % D_dButton, OButton, O_R0Button, RButton, TButton, 
        % T_R0Button, T_RButton, aButton, bButton
        function plotSignalButton(app, event)
            plotSignal(app,event.Source.Text)
        end

        % Button pushed function: BackgroundButton, CouplingButton, 
        % DelayR0Button, DelayR0Button_2, DelayRButton, 
        % DelayRButton_2, DelaydButton, RXantennaButton, 
        % SourceButton, TXantennaButton, TagButton
        function systemBlockButtonPushed(app, event)
            block=strrep( event.Source.Text , ' ' , '_' );
            if app.shift==0
                plotBlockResponse(app,block);
            else
                app.systemBlockList.Value=block;
                app.TabGroup.SelectedTab=app.blockSetupTab;
                app.shift=0;
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
             assignin("base","event",event)
            switch key
                case 'shift'
                    app.shift=1;
                case 'escape'
                    delete(app)
                case '1'
                    app.TabGroup.SelectedTab=app.SystemTab;
                case '2'
                    app.TabGroup.SelectedTab=app.SetupTab;
                case '3'
                    app.TabGroup.SelectedTab=app.blockSetupTab;
            end
        end

        % Callback function: DroptobaseworkspaceMenu, 
        % droptobaseworkspaceButton
        function droptobaseworkspaceButtonPushed(app, event)
            assignin("base","troubleshoot",app.systemStruct)
        end

        % Menu selected function: DummyMenu
        function DummyMenuSelected(app, event)
            if strcmp(app.Menu_2.Checked,'off')
                app.Menu_2.Checked= 'on';
            else
                app.Menu_2.Checked='off';
            end
        end

        % Callback function
        function InputpulseDropDownValueChanged(app, event)
            value = app.InputpulseDropDown.Value;
            switch value
                case 'Impulse'
                    app.TabGroup2.SelectedTab=app.impulsesetupTab;
                case 'short pulse'
                    app.TabGroup2.SelectedTab=app.pulsesetupTab;
            end
        end

        % Callback function: SaveButton, SaveMenu
        function SaveButtonPushed(app, event)
            app.systemStruct.(app.systemBlockList.Value).response=app.gainEditField.Value;
            app.TabGroup.SelectedTab=app.SystemTab;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.systemStruct=updateSystem(app,app.systemStruct);
            assignin('base','t',app.systemStruct.T);
        end

        % Window key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'shift'
                    app.shift=0;
            end
        end

        % Button pushed function: SelectTaglocationButton
        function SelectTaglocationButtonPushed(app, event)
            app.systemSideView=SelectTaglocation(app.systemSideView);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DummyMenu = uimenu(app.Menu);
            app.DummyMenu.MenuSelectedFcn = createCallbackFcn(app, @DummyMenuSelected, true);
            app.DummyMenu.Text = 'Dummy';
            app.ShortcutsMenu = uimenu(app.UIFigure);
            app.ShortcutsMenu.Text = 'Shortcuts';
            app.DroptobaseworkspaceMenu = uimenu(app.ShortcutsMenu);
            app.DroptobaseworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.DroptobaseworkspaceMenu.Accelerator = 'd';
            app.DroptobaseworkspaceMenu.Text = 'Drop to base workspace';
            app.UpdatesystemMenu = uimenu(app.ShortcutsMenu);
            app.UpdatesystemMenu.Accelerator = 'u';
            app.UpdatesystemMenu.Text = 'Update system';
            app.SaveMenu = uimenu(app.ShortcutsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.TButton = uibutton(app.SystemTab, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.TButton.Text = 'T';
            app.impulseResponse = uiaxes(app.SystemTab);
            title(app.impulseResponse, 'Impulse response')
            xlabel(app.impulseResponse, 'time (s)')
            ylabel(app.impulseResponse, 'Y')
            app.amplitudeResponse = uiaxes(app.SystemTab);
            title(app.amplitudeResponse, 'Frequency response (amplitude)')
            xlabel(app.amplitudeResponse, 'Frequency')
            ylabel(app.amplitudeResponse, '|Y|')
            app.phaseResponse = uiaxes(app.SystemTab);
            title(app.phaseResponse, 'Frequency response (angle)')
            xlabel(app.phaseResponse, 'Frequency')
            ylabel(app.phaseResponse, '\angleY')
            app.aButton = uibutton(app.SystemTab, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.aButton.Text = 'a';
            app.CouplingButton = uibutton(app.SystemTab, 'push');
            app.CouplingButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.CouplingButton.Text = 'Coupling';
            app.TXantennaButton = uibutton(app.SystemTab, 'push');
            app.TXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TXantennaButton.Text = 'TX antenna';
            app.DButton = uibutton(app.SystemTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.DButton.Text = 'D';
            app.DelaydButton = uibutton(app.SystemTab, 'push');
            app.DelaydButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelaydButton.Text = 'Delay d';
            app.DelayRButton = uibutton(app.SystemTab, 'push');
            app.DelayRButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayRButton.Text = 'Delay R';
            app.DelayR0Button_2 = uibutton(app.SystemTab, 'push');
            app.DelayR0Button_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayR0Button_2.Text = 'Delay R0';
            app.TagButton = uibutton(app.SystemTab, 'push');
            app.TagButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TagButton.Text = 'Tag';
            app.CButton = uibutton(app.SystemTab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.CButton.Text = 'C';
            app.BackgroundButton = uibutton(app.SystemTab, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.OButton = uibutton(app.SystemTab, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.OButton.Text = 'O';
            app.Label = uilabel(app.SystemTab);
            app.Label.Text = {'------------------------------------------------------------------'; '            |                                   |                              |'};
            app.SourceButton = uibutton(app.SystemTab, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.Label_2 = uilabel(app.SystemTab);
            app.Label_2.Text = {'            |                                   |                              |'; '------------------------------------------------------------------'};
            app.RXantennaButton = uibutton(app.SystemTab, 'push');
            app.RXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.RXantennaButton.Text = 'RX antenna';
            app.DetectorButton = uibutton(app.SystemTab, 'push');
            app.DetectorButton.Text = 'Detector';
            app.bButton = uibutton(app.SystemTab, 'push');
            app.bButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.bButton.Text = 'b';
            app.D_dButton = uibutton(app.SystemTab, 'push');
            app.D_dButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.D_dButton.Text = 'D_d';
            app.T_RButton = uibutton(app.SystemTab, 'push');
            app.T_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_RButton.Text = 'T_R';
            app.T_R0Button = uibutton(app.SystemTab, 'push');
            app.T_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_R0Button.Text = 'T_R0';
            app.DelayRButton_2 = uibutton(app.SystemTab, 'push');
            app.DelayRButton_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayRButton_2.Text = 'Delay R';
            app.DelayR0Button = uibutton(app.SystemTab, 'push');
            app.DelayR0Button.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayR0Button.Text = 'Delay R0';
            app.C_RButton = uibutton(app.SystemTab, 'push');
            app.C_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.C_RButton.Text = 'C_R';
            app.O_R0Button = uibutton(app.SystemTab, 'push');
            app.O_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.O_R0Button.Text = 'O_R0';
            app.RButton = uibutton(app.SystemTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.RButton.Text = 'R';
            app.dEditFieldLabel = uilabel(app.SystemTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SystemTab, 'numeric');
            app.REditFieldLabel = uilabel(app.SystemTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SystemTab, 'numeric');
            app.R0EditFieldLabel = uilabel(app.SystemTab);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.SystemTab, 'numeric');
            app.NoiseButton = uibutton(app.SystemTab, 'push');
            app.NoiseButton.Text = 'Noise';
            app.NoiseButton_2 = uibutton(app.SystemTab, 'push');
            app.NoiseButton_2.Text = 'Noise';
            app.UpdateButton = uibutton(app.SystemTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.systemSideView = uiaxes(app.SystemTab);
            title(app.systemSideView, 'Title')
            xlabel(app.systemSideView, 'X')
            ylabel(app.systemSideView, 'Y')
            app.SelectTaglocationButton = uibutton(app.SystemTab, 'push');
            app.SelectTaglocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.SelectTaglocationButton.Text = 'Select Tag location';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.droptobaseworkspaceButton = uibutton(app.SetupTab, 'push');
            app.droptobaseworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.droptobaseworkspaceButton.Text = 'drop to base workspace';
            app.blockSetupTab = uitab(app.TabGroup);
            app.blockSetupTab.Title = 'block setup';
            app.systemBlockList = uilistbox(app.blockSetupTab);
            app.systemBlockList.Items = {'Source', 'TX_antenna', 'Coupling', 'Tag', 'Background', 'RX_antenna', 'Detector'};
            app.systemBlockList.Value = 'Source';
            app.gainEditFieldLabel = uilabel(app.blockSetupTab);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.blockSetupTab, 'numeric');
            app.SaveButton = uibutton(app.blockSetupTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = systemModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end